classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        aLabel                     matlab.ui.control.Label
        aEditField                 matlab.ui.control.EditField
        bLabel                     matlab.ui.control.Label
        bEditField                 matlab.ui.control.EditField
        t0Label                    matlab.ui.control.Label
        t0EditField                matlab.ui.control.EditField
        t1Label                    matlab.ui.control.Label
        t1EditField                matlab.ui.control.EditField
        cxtududxEditFieldLabel     matlab.ui.control.Label
        cEditField                 matlab.ui.control.EditField
        fxtududxEditFieldLabel     matlab.ui.control.Label
        fEditField                 matlab.ui.control.EditField
        sxtududxEditFieldLabel     matlab.ui.control.Label
        sEditField                 matlab.ui.control.EditField
        p1xlxrulurtEditFieldLabel  matlab.ui.control.Label
        p1EditField                matlab.ui.control.EditField
        p2xlxrulurtEditFieldLabel  matlab.ui.control.Label
        p2EditField                matlab.ui.control.EditField
        q1xlxrulurtEditFieldLabel  matlab.ui.control.Label
        q1EditField                matlab.ui.control.EditField
        q2xlxrulurtEditFieldLabel  matlab.ui.control.Label
        q2EditField                matlab.ui.control.EditField
        clerButton                 matlab.ui.control.Button
        solveButton                matlab.ui.control.Button
        u0xEditFieldLabel          matlab.ui.control.Label
        u0EditField                matlab.ui.control.EditField
        Label                      matlab.ui.control.Label
        asymptoticsButton          matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: clerButton
        function clerButtonPushed(app, event)
            app.aEditField.Value = '';
            app.bEditField.Value = '';
            app.t0EditField.Value = '';
            app.t1EditField.Value = '';
            
            app.cEditField.Value = '';
            app.fEditField.Value = '';
            app.sEditField.Value = '';
           
            app.p1EditField.Value = '';
            app.p2EditField.Value = '';
            app.q1EditField.Value = '';
            app.q2EditField.Value = '';
            
            app.u0EditField.Value = '';
        
        end

        % Button pushed function: solveButton
        function solveButtonPushed(app, event)
            [a, b, t0, t1, pdefun, icfun, bcfun] = getValues(app);
            
            m = 0;
            x = linspace(a, b, 100);
            t = linspace(t0, t1, 1000);
            
            %sol = pdepe(m,@pdex1pde,@pdex1ic,@pdex1bc,x,t);
            sol = pdepe(m, pdefun, icfun, bcfun, x, t);
            % Extract the first solution component as u.
            u = sol(:,:,1);
           
            %surf(app.UIAxes, x,t,u); 
            %title('Numerical solution computed with 20 mesh points.');
            %xlabel('Distance x');
            %ylabel('Time t');
            
            close all;
            
            h = animatedline;
            m = min(min(u));
            M = max(max(u));
            
            axis([a - (b - a) * 0.1,b + (b - a) * 0.1,m - (M - m) * 0.1,M + (M - m) * 0.1])
            
            for time = 1 : length(t)
                clearpoints(h);
                addpoints(h, x, u(time,:));
                
                xlabel('x');
                ylabel('u');
                %pause(1/100);
                drawnow
            end
            
        end

        % Button pushed function: asymptoticsButton
        function asymptoticsButtonPushed(app, event)
            [a, b, t0, t1, pdefun, icfun, bcfun] = getValues(app);

            m = 0;
            x = linspace(a, b, 100);
            i = 1;
            eps = 0.0001;
            
            while (true)
                
                t = linspace(t0, t0 + (t1 - t0) * i, 100 * i);
                
                %sol = pdepe(m,@pdex1pde,@pdex1ic,@pdex1bc,x,t);
                sol = pdepe(m, pdefun, icfun, bcfun, x, t);
                % Extract the first solution component as u.
                u = sol(:,:,1);
           
                diff = u(end, :) - u(end - 1, :);
                
                if (norm(diff, inf) < eps)
                   break; 
                end
                i = i + 1;
            end
            disp('++');
            fig = figure('name', 'asymptotics');
            plot(x, u(end, :));
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Text = 'a =';
            app.aEditField = uieditfield(app.UIFigure, 'text');
            app.aEditField.Value = '0';
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Text = 'b =';
            app.bEditField = uieditfield(app.UIFigure, 'text');
            app.bEditField.Value = '1';
            app.t0Label = uilabel(app.UIFigure);
            app.t0Label.Text = 't0 =';
            app.t0EditField = uieditfield(app.UIFigure, 'text');
            app.t0EditField.Value = '0';
            app.t1Label = uilabel(app.UIFigure);
            app.t1Label.Text = 't1 =';
            app.t1EditField = uieditfield(app.UIFigure, 'text');
            app.t1EditField.Value = '5';
            app.cxtududxEditFieldLabel = uilabel(app.UIFigure);
            app.cxtududxEditFieldLabel.Text = 'c (x t u dudx) =';
            app.cEditField = uieditfield(app.UIFigure, 'text');
            app.cEditField.Value = 'pi^2';
            app.fxtududxEditFieldLabel = uilabel(app.UIFigure);
            app.fxtududxEditFieldLabel.Text = 'f (x t u dudx) =';
            app.fEditField = uieditfield(app.UIFigure, 'text');
            app.fEditField.Value = 'dudx';
            app.sxtududxEditFieldLabel = uilabel(app.UIFigure);
            app.sxtududxEditFieldLabel.Text = 's (x t u dudx) =';
            app.sEditField = uieditfield(app.UIFigure, 'text');
            app.sEditField.Value = '0';
            app.p1xlxrulurtEditFieldLabel = uilabel(app.UIFigure);
            app.p1xlxrulurtEditFieldLabel.Text = 'p1 (xl xr ul ur t) =';
            app.p1EditField = uieditfield(app.UIFigure, 'text');
            app.p1EditField.Value = 'ul';
            app.p2xlxrulurtEditFieldLabel = uilabel(app.UIFigure);
            app.p2xlxrulurtEditFieldLabel.Text = 'p2 (xl xr ul ur t) =';
            app.p2EditField = uieditfield(app.UIFigure, 'text');
            app.p2EditField.Value = 'pi * exp(-t)';
            app.q1xlxrulurtEditFieldLabel = uilabel(app.UIFigure);
            app.q1xlxrulurtEditFieldLabel.Text = 'q1 (xl xr ul ur t) =';
            app.q1EditField = uieditfield(app.UIFigure, 'text');
            app.q1EditField.Value = '0';
            app.q2xlxrulurtEditFieldLabel = uilabel(app.UIFigure);
            app.q2xlxrulurtEditFieldLabel.Text = 'q2 (xl xr ul ur t) =';
            app.q2EditField = uieditfield(app.UIFigure, 'text');
            app.q2EditField.Value = '1';
            app.clerButton = uibutton(app.UIFigure, 'push');
            app.clerButton.ButtonPushedFcn = createCallbackFcn(app, @clerButtonPushed, true);
            app.clerButton.Text = 'Очистить';
            app.solveButton = uibutton(app.UIFigure, 'push');
            app.solveButton.ButtonPushedFcn = createCallbackFcn(app, @solveButtonPushed, true);
            app.solveButton.Text = 'Решить';
            app.u0xEditFieldLabel = uilabel(app.UIFigure);
            app.u0xEditFieldLabel.Text = 'u0(x) =';
            app.u0EditField = uieditfield(app.UIFigure, 'text');
            app.u0EditField.Value = 'sin(pi * x)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Решение краевой задачи для'; 'одномерного уравнения теплопроводности'};
            app.asymptoticsButton = uibutton(app.UIFigure, 'push');
            app.asymptoticsButton.ButtonPushedFcn = createCallbackFcn(app, @asymptoticsButtonPushed, true);
            app.asymptoticsButton.Text = 'Асимптотика';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end